classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        GridLayout        matlab.ui.container.GridLayout
        OptionsPanel      matlab.ui.container.Panel
        PlotButton        matlab.ui.control.Button
        NSpinner          matlab.ui.control.Spinner
        NSpinnerLabel     matlab.ui.control.Label
        XminSlider        matlab.ui.control.Slider
        XminSliderLabel   matlab.ui.control.Label
        XmaxSlider        matlab.ui.control.Slider
        XmaxSliderLabel   matlab.ui.control.Label
        XstepSlider       matlab.ui.control.Slider
        XstepSliderLabel  matlab.ui.control.Label
        PlotRight         matlab.ui.control.UIAxes
        PlotLeft          matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        function plotSinsAndErros(app, x, n)
            legend(app.PlotLeft, "off");
            legend(app.PlotRight, "off");
           
            y = sin(x);
            values = 1:n;
            % plot original sin
            hold(app.PlotLeft, "on");
            plot(app.PlotLeft, x, y, "LineWidth", 2, LineStyle="--");

            % plot the series approximations
            Legend = cell(n+1,1);
            Legend{1} = "sin(x)";
            for val = values
                hold(app.PlotLeft, "on");
                y2 = szeregSin(x, val);
                plot(app.PlotLeft, x, y2);
                Legend{val+1} = append("N = ", int2str(val));
            end

            % display the left plot and the legend
            hold(app.PlotLeft, "off");
            title(app.PlotLeft, "Sinus i szeregi");
            legend(app.PlotLeft, Legend);

            % plot the serries approximations errors
            Legend = cell(n,1);
            for val = values
                hold(app.PlotRight, "on");
                y2 = szeregSin(x, val);
                y3 = blad(y2, y);
                plot(app.PlotRight, x, y3);
                Legend{val} = append("N = ", int2str(val));
            end
            hold(app.PlotRight, "off");
            title(app.PlotRight, "Bledy wzgledne");
            legend(app.PlotRight, Legend);

        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)

%             x = linspace(-pi, pi, 50);
            x = app.XminSlider.Value : app.XstepSlider.Value : app.XmaxSlider.Value;
            n = app.NSpinner.Value;
            plotSinsAndErros(app, x, n);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.PlotLeft = uiaxes(app.GridLayout);
            title(app.PlotLeft, 'Title')
            xlabel(app.PlotLeft, 'X')
            ylabel(app.PlotLeft, 'Y')
            zlabel(app.PlotLeft, 'Z')
            app.PlotRight = uiaxes(app.GridLayout);
            title(app.PlotRight, 'Title')
            xlabel(app.PlotRight, 'X')
            ylabel(app.PlotRight, 'Y')
            zlabel(app.PlotRight, 'Z')
            app.OptionsPanel = uipanel(app.GridLayout);
            app.OptionsPanel.Title = 'Options';
            app.XstepSliderLabel = uilabel(app.OptionsPanel);
            app.XstepSliderLabel.Text = 'X step';
            app.XstepSlider = uislider(app.OptionsPanel);
            app.XstepSlider.Limits = [0 1];
            app.XmaxSliderLabel = uilabel(app.OptionsPanel);
            app.XmaxSliderLabel.Text = 'X max';
            app.XmaxSlider = uislider(app.OptionsPanel);
            app.XmaxSlider.Limits = [-10 10];
            app.XminSliderLabel = uilabel(app.OptionsPanel);
            app.XminSliderLabel.Text = 'X min';
            app.XminSlider = uislider(app.OptionsPanel);
            app.XminSlider.Limits = [-10 10];
            app.NSpinnerLabel = uilabel(app.OptionsPanel);
            app.NSpinnerLabel.Text = 'N';
            app.NSpinner = uispinner(app.OptionsPanel);
            app.NSpinner.Limits = [1 Inf];
            app.NSpinner.Value = 1;
            app.PlotButton = uibutton(app.OptionsPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end