classdef kwadWiz < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout             matlab.ui.container.GridLayout
        RightPlotLabel         matlab.ui.control.Label
        MiddlePlotLabel        matlab.ui.control.Label
        LeftPlotLabel          matlab.ui.control.Label
        OptionsPanel           matlab.ui.container.Panel
        ProperLabel            matlab.ui.control.Label
        NSpinner               matlab.ui.control.Spinner
        NSpinnerLabel          matlab.ui.control.Label
        FunctionDropDown       matlab.ui.control.DropDown
        FunctionDropDownLabel  matlab.ui.control.Label
        EndSlider              matlab.ui.control.Slider
        EndSliderLabel         matlab.ui.control.Label
        BeginSlider            matlab.ui.control.Slider
        BeginSliderLabel       matlab.ui.control.Label
        PlotButton             matlab.ui.control.StateButton
        RightPlot              matlab.ui.control.UIAxes
        LeftPlot               matlab.ui.control.UIAxes
        MiddlePlot             matlab.ui.control.UIAxes
    end

    
    methods (Access = public)

        function [wynik] = plotAndCalc(comp, choosenPlot, f, wezly, koniec, H)
            % Wyznacznie kwadratury

            cla(choosenPlot);
            [wynik, wysokosci, punkty] = kwadProst(wezly, f, koniec, H);
            
            % Wartosci dla funkcji podcałkowej
            a = wezly(1);
            b = wezly(end);
            n = length(wezly);
            x = linspace(a, b, 10000);
            y = f(x);
            
            hold(choosenPlot, "on");

            %xticks(wezly);
            
            % Plottowanie fukcji podcalkowej 
            plot(choosenPlot,x, y, LineWidth=2);
            
            % Naniesienie wezłów
            plot(choosenPlot, punkty, wysokosci, "ro")
            
            % Obrazowanie wysokosci i pola kazdego prosotkatów
            for i = 2:n
            
                % Linia wysokosci prostokata
                xx = [wezly(i-1), wezly(i)];
                yy = [wysokosci(i-1), wysokosci(i-1)];
                plot(choosenPlot, xx, yy, "g", LineWidth=1.5);
               
                dlg = wezly(i) - wezly(i - 1);
                wys = abs(wysokosci(i - 1));
            
                % Pole prostokąta
                if wysokosci(i - 1) < 0
                    pos = [wezly(i - 1), wysokosci(i - 1), dlg, wys];
                else
                    pos = [wezly(i - 1), 0, dlg, wys];
                end
                rectangle(choosenPlot, "Position", pos, "FaceColor", [0 1 0 0.05], "EdgeColor", [0 1 0 0.05]);
            
            end
            
            % Osie OX i OY
            % xline(0);
            % yline(0);
            
            % Utworzenie legendy
            legend(choosenPlot, "y = sin x", "wezly", "wysokosci");
            hold(choosenPlot, "off");
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: PlotButton
        function PlotButtonPushed(comp, event)
            a = comp.BeginSlider.Value;
            b = comp.EndSlider.Value;
            n = comp.NSpinner.Value;
            H = (b - a) / n;

            wezly = linspace(a, b, n);
            switch comp.FunctionDropDown.Value
                case "sin x"
                    f = @sin;
                    F = @(x) -cos(x);
                case "sin 2x"
                    f = @(x) sin(2 * x);
                    F = @(x) (-1/2) * cos(2 * x);
                case "cos x"
                    f = @cos;
                    F = @(x) sin(x);
                case "cos 2x"
                    f = @(x) cos(2 * x);
                    F = @(x) (1/2) * sin(2 * x);
                case "e^x"
                    f = @exp;
                    F = @exp;
                case "ln x"
                    f = @log;
                    F = @(x) (x .* log(x)) - x;
                case "-ln x"
                    f = @(x) -log(x);
                    F = @(x) x - (x .* log(x));
                case "1/x"
                    f = @(x) 1 ./ x;
                    F = @(x) log(abs(x));
                case "x"
                    f = @(x) x;
                    F = @(x) (1/2) * (x.^2);
                case "x^2"
                    f = @(x) x.^2;
                    F = @(x) (1/3) * (x.^3);
                case "sqrt(x)"
                    f = @sqrt;
                    F = @(x) (2/3) * (sqrt(x)).^3;
                otherwise
                    f = @(x) 1;
                    F = @(x) x;
            end
            
            properRes = F(b) - F(a);

            leftRes = comp.plotAndCalc(comp.LeftPlot, f, wezly, KonceKwad.Lewy, H);
            middleRes = comp.plotAndCalc(comp.MiddlePlot, f, wezly, KonceKwad.Srodek, H);
            rightRes = comp.plotAndCalc(comp.RightPlot, f, wezly, KonceKwad.Prawy, H);

            leftError = abs(leftRes - properRes);
            middleError = abs(middleRes - properRes);
            rightError = abs(rightRes - properRes);

            comp.LeftPlotLabel.Text = sprintf(strcat("Wynik: ", num2str(leftRes), "\nBłąd: ", num2str(leftError)));
            comp.MiddlePlotLabel.Text = sprintf(strcat("Wynik: ", num2str(middleRes), "\nBłąd: ", num2str(middleError)));
            comp.RightPlotLabel.Text = sprintf(strcat("Wynik: ", num2str(rightRes), "\nBłąd: ", num2str(rightError)));

            comp.ProperLabel.Text = sprintf(strcat("Poprawny Wynik:\n", num2str(properRes)));
        end

        % Value changed function: FunctionDropDown
        function FunctionChange(comp, event)
            comp.BeginSlider.Limits = [-10, 20];
            comp.EndSlider.Limits = [-10, 20];
            switch comp.FunctionDropDown.Value
                case {"ln x", "1/x", "sqrt(x)", "-ln x"}
                    comp.BeginSlider.Limits = [0, 20];
                    comp.EndSlider.Limits = [0, 20];   
            end
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 722 500];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.GridLayout = uigridlayout(comp);
            comp.MiddlePlot = uiaxes(comp.GridLayout);
            title(comp.MiddlePlot, 'Kwadratura ze środkiem')
            xlabel(comp.MiddlePlot, 'X')
            ylabel(comp.MiddlePlot, 'Y')
            zlabel(comp.MiddlePlot, 'Z')
            comp.LeftPlot = uiaxes(comp.GridLayout);
            title(comp.LeftPlot, 'Kwadratura z lewym końcem')
            xlabel(comp.LeftPlot, 'X')
            ylabel(comp.LeftPlot, 'Y')
            zlabel(comp.LeftPlot, 'Z')
            comp.RightPlot = uiaxes(comp.GridLayout);
            title(comp.RightPlot, 'Kwadratura z prawym końcem')
            xlabel(comp.RightPlot, 'X')
            ylabel(comp.RightPlot, 'Y')
            zlabel(comp.RightPlot, 'Z')
            comp.OptionsPanel = uipanel(comp.GridLayout);
            comp.OptionsPanel.Title = 'Options';
            comp.PlotButton = uibutton(comp.OptionsPanel, 'state');
            comp.PlotButton.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @PlotButtonPushed, true);
            comp.PlotButton.Text = 'Plot';
            comp.BeginSliderLabel = uilabel(comp.OptionsPanel);
            comp.BeginSliderLabel.Text = 'Begin';
            comp.BeginSlider = uislider(comp.OptionsPanel);
            comp.BeginSlider.Limits = [-10 20];
            comp.BeginSlider.Value = -1;
            comp.EndSliderLabel = uilabel(comp.OptionsPanel);
            comp.EndSliderLabel.Text = 'End';
            comp.EndSlider = uislider(comp.OptionsPanel);
            comp.EndSlider.Limits = [-10 20];
            comp.EndSlider.Value = 1;
            comp.FunctionDropDownLabel = uilabel(comp.OptionsPanel);
            comp.FunctionDropDownLabel.Text = 'Function';
            comp.FunctionDropDown = uidropdown(comp.OptionsPanel);
            comp.FunctionDropDown.Items = {'sin x', 'sin 2x', 'cos x', 'cos 2x', 'e^x', 'ln x', '-ln x', '1/x', 'x', 'x^2', 'sqrt(x)'};
            comp.FunctionDropDown.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @FunctionChange, true);
            comp.FunctionDropDown.Value = 'sin x';
            comp.NSpinnerLabel = uilabel(comp.OptionsPanel);
            comp.NSpinnerLabel.Text = 'N';
            comp.NSpinner = uispinner(comp.OptionsPanel);
            comp.NSpinner.Limits = [2 500];
            comp.NSpinner.Value = 2;
            comp.ProperLabel = uilabel(comp.OptionsPanel);
            comp.ProperLabel.Text = 'Poprawny Wynik:';
            comp.LeftPlotLabel = uilabel(comp.GridLayout);
            comp.LeftPlotLabel.Text = {'Wynik:'; 'Błąd:'};
            comp.MiddlePlotLabel = uilabel(comp.GridLayout);
            comp.MiddlePlotLabel.Text = {'Wynik:'; 'Błąd:'};
            comp.RightPlotLabel = uilabel(comp.GridLayout);
            comp.RightPlotLabel.Text = {'Wynik:'; 'Błąd:'};
        end
    end
end